classdef paleoDataSearch < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        DataDownloadButton    matlab.ui.control.Button
        StudyLocation         matlab.ui.container.Panel
        GotostudypageButton   matlab.ui.control.Button
        StudyPageTextArea     matlab.ui.control.TextArea
        Label_StudyPage       matlab.ui.control.Label
        CitationTextArea      matlab.ui.control.TextArea
        Label_Citation        matlab.ui.control.Label
        OriginatorTextArea    matlab.ui.control.TextArea
        Label_Originator      matlab.ui.control.Label
        SearchResults         matlab.ui.control.ListBox
        Label_DataType_10     matlab.ui.control.Label
        TabGroup              matlab.ui.container.TabGroup
        GeneralSearchTab      matlab.ui.container.Tab
        ClearButton           matlab.ui.control.Button
        LonMax                matlab.ui.control.NumericEditField
        LatMax                matlab.ui.control.NumericEditField
        MapAxes               matlab.ui.container.Panel
        Label_Latitude        matlab.ui.control.Label
        Label_Longitude       matlab.ui.control.Label
        LonMin                matlab.ui.control.NumericEditField
        Label_MaxLon          matlab.ui.control.Label
        Label_MinLon          matlab.ui.control.Label
        LatMin                matlab.ui.control.NumericEditField
        Label_MaxLat          matlab.ui.control.Label
        Label_MinLat          matlab.ui.control.Label
        SearchButton          matlab.ui.control.Button
        APIURL                matlab.ui.control.TextArea
        DataTypeList          matlab.ui.control.ListBox
        Label_DataTypeTip     matlab.ui.control.Label
        Label_DataType        matlab.ui.control.Label
        Label_APIURL          matlab.ui.control.Label
        AdvancedSearchTab_2   matlab.ui.container.Tab
        TimeMax               matlab.ui.control.NumericEditField
        TimeMin               matlab.ui.control.NumericEditField
        Label_LatestTime      matlab.ui.control.Label
        Label_EarliestTime    matlab.ui.control.Label
        Label_Time            matlab.ui.control.Label
        AdvancedClearButton   matlab.ui.control.Button
        AdvancedSearchButton  matlab.ui.control.Button
        InvestigatorList      matlab.ui.control.ListBox
        Label_Investigator    matlab.ui.control.Label
        ElevMax               matlab.ui.control.NumericEditField
        Label_Elevation       matlab.ui.control.Label
        ElevMin               matlab.ui.control.NumericEditField
        Label_ElevMax         matlab.ui.control.Label
        Label_ElevMin         matlab.ui.control.Label
        AdvancedLonMax        matlab.ui.control.NumericEditField
        AdvancedLatMax        matlab.ui.control.NumericEditField
        Label_Latitude_2      matlab.ui.control.Label
        Label_Longitude_2     matlab.ui.control.Label
        AdvancedLonMin        matlab.ui.control.NumericEditField
        Label_MaxLon_2        matlab.ui.control.Label
        Label_MinLon_2        matlab.ui.control.Label
        AdvancedLatMin        matlab.ui.control.NumericEditField
        Label_MaxLat_2        matlab.ui.control.Label
        Label_MinLat_2        matlab.ui.control.Label
        AdvancedAPIURL        matlab.ui.control.TextArea
        AdvancedDataTypeList  matlab.ui.control.ListBox
        Label_DataTypeTip_2   matlab.ui.control.Label
        Label_DataType_11     matlab.ui.control.Label
        Label_APIURL_2        matlab.ui.control.Label
        ButtonGroup           matlab.ui.container.ButtonGroup
        calyrBP01950CEButton  matlab.ui.control.RadioButton
        CEButton              matlab.ui.control.RadioButton
        COLORSLabel           matlab.ui.control.Label
        Label_14              matlab.ui.control.Label
        Label_12              matlab.ui.control.Label
        Label_11              matlab.ui.control.Label
        Label_10              matlab.ui.control.Label
        Label_9               matlab.ui.control.Label
        Label_8               matlab.ui.control.Label
        HighlightLabel        matlab.ui.control.Label
        H3BackgroundLabel     matlab.ui.control.Label
        ButtonLabel           matlab.ui.control.Label
        ForegroundLabel       matlab.ui.control.Label
        H2BackgroundLabel     matlab.ui.control.Label
        H1BackgroundLabel     matlab.ui.control.Label
    end

    
    properties (Access = private)
        api_base % Base URL for the API
        map_axes % Map axes
        map_axes2 % Seond map axes
        RobinsonProjectedCRS % Map projection
        MercatorProjectedCRS % Map projection
        api_full_json % Full JSON struct
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %
            % Populate dataType list
            %
            dataTypeIds = get_dataTypeId();
            dataTypes = fieldnames(dataTypeIds);
             %General Search
               app.DataTypeList.Items=dataTypes;
               app.DataTypeList.Multiselect='off';
             %Advanced Search
               app.AdvancedDataTypeList.Items=dataTypes;
               app.AdvancedDataTypeList.Multiselect='on';
               app.InvestigatorList.Items={'N/A'};

            %
            % Get API URL
            %
            app.api_base = app.APIURL.Value;

            %
            % Create map axes
            %
            % Study Distributions
            land = readgeotable("landareas.shp");
            app.map_axes = mapaxes(app.MapAxes,NextPlot="add",Color=[1 1 1]);
            geoplot(app.map_axes,land,FaceColor=[0.85 0.85 0.85],EdgeColor=[0.13 0.13 0.13]);
            app.RobinsonProjectedCRS = projcrs(54030,Authority="ESRI");
            app.map_axes.ProjectedCRS = app.RobinsonProjectedCRS;
            % Study Locations
            app.map_axes2 = mapaxes(app.StudyLocation,NextPlot="add",Color=[1 1 1]);
            geoplot(app.map_axes2,land,FaceColor=[0.85 0.85 0.85],EdgeColor=[0.13 0.13 0.13]);
            app.MercatorProjectedCRS = projcrs(3395,Authority="EPSG");
            app.map_axes2.ProjectedCRS = app.MercatorProjectedCRS;
        end

        % Button pushed function: SearchButton
        function SearchButtonPushed(app, event)
            %
            % Update API URL
            %
            dataTypeIds = get_dataTypeId();
            fields = fieldnames(dataTypeIds);
            user_selections = app.DataTypeList.Value;
            idx = contains(fields,user_selections);
            % Data Types
            if isscalar(user_selections)
                id = dataTypeIds.(fields{idx});
                update_api = [char(string(app.api_base)) '&dataTypeId=' num2str(id)];
                app.APIURL.Value = update_api;
            else
                ids = cell2mat(struct2cell(dataTypeIds));
                ids = ids(idx);
                link = '%7C';
                update_api = [char(string(app.api_base)) '&dataTypeId=' num2str(ids(1))];
                for id = 2:length(ids)
                  update_api = [update_api link num2str(ids(id))];
                end
                app.APIURL.Value = update_api;
            end
            % Latitude/Longitude
            latMin = app.LatMin.Value;
            latMax = app.LatMax.Value;
            lonMin = app.LonMin.Value;
            lonMax = app.LonMax.Value;
            if latMin ~= -90
             app.APIURL.Value = [char(app.APIURL.Value) '&minLat=' num2str(latMin)];
            end
            if latMax ~= 90
             app.APIURL.Value = [char(app.APIURL.Value) '&maxLat=' num2str(latMax)];
            end
            if lonMin ~= -180
             app.APIURL.Value = [char(app.APIURL.Value) '&minLon=' num2str(lonMin)];
            end
            if lonMax ~= 180
             app.APIURL.Value = [char(app.APIURL.Value) '&maxLon=' num2str(lonMax)];
            end
            % Finalize API URL
            app.APIURL.Value = [char(app.APIURL.Value) '&headersOnly=true'];

            %
            % Search results
            %
            api_url = char(app.APIURL.Value);
            options = weboptions('Timeout', 60);
            api_json = webread(api_url,options);
            study_names = {api_json.study.studyName};
            none = {'None'};
            study_names = [none; study_names'];
            app.SearchResults.Items=study_names;

            %
            % Update map
            %
            cla(app.map_axes);
            land = readgeotable("landareas.shp");
            app.map_axes = mapaxes(app.MapAxes,NextPlot="add",Color=[1 1 1]);
            geoplot(app.map_axes,land,FaceColor=[0.85 0.85 0.85],EdgeColor=[0.13 0.13 0.13]);
            app.map_axes.ProjectedCRS = app.RobinsonProjectedCRS;

            % Get full header data
            api_full_url = api_url;
            api_full_url(end-16:end) = []; % Deletes &headersOnly=true
            app.api_full_json = webread(api_full_url,options);
            % Initialize latitude & longitude vectors
            lats = [];
            lons = []; 
            % Loop through sites
            num_studies = length(app.api_full_json.study);  % Count number of studies returned
            study_list = app.api_full_json.study;  % Extract list of studies returned
            for i = 1:num_studies  % Loop through studies
                num_sites = length(study_list(i).site);  % Count number of sites in the study
                for j = 1:num_sites  % Loop through sites within the study
                    % Extract latitude, convert to double, and append to vector
                    if isempty(study_list(i).site(j).geo)
                        continue
                    end
                    lat = study_list(i).site(j).geo.geometry.coordinates(1);
                    lon = study_list(i).site(j).geo.geometry.coordinates(2);
                    lats = [lats; str2double(lat{1})];
                    lons = [lons; str2double(lon{1})];
                end
            end

            % Add scatter points to distribution map
            C = [244 50 12]./255; % Red
            gs=geoscatter(app.map_axes,lats,lons);
            gs.MarkerFaceColor=C;
            gs.MarkerEdgeColor='k';
            gs.SizeData=15;
            %{
              I have not decided whether I like MapLimits being edited or
              or not. If so, un-comment the following line:
            %}
            % geolimits(app.map_axes,[latMin latMax],[lonMin lonMax])

            % Add scatter points to the study location map
            % First study in list only
% number_of_sites = length(app.api_full_json.study(1).site);
% if number_of_sites==1
%     lat=str2double(app.api_full_json.study(idx).site(1).geo.geometry.coordinates(1));
%     lon=str2double(app.api_full_json.study(idx).site(1).geo.geometry.coordinates(2));
% elseif number_of_sites > 1
%     lat=[];
%     lon=[];
%     for latitude = 1:number_of_sites
%     for longitude= 1:number_of_sites
%        lat_val = str2double(app.api_full_json.study(idx).site(latitude).geo.geometry.coordinates(1));
%        lon_val = str2double(app.api_full_json.study(idx).site(longitude).geo.geometry.coordinates(2));
%        lat = [lat;lat_val];
%        lon = [lon;lon_val];
%     end
%     end
% end
%  gs=geoscatter(app.map_axes2,lat,lon);
%  gs.MarkerFaceColor=C;
%  gs.MarkerEdgeColor='k';
%  gs.SizeData=30;

            %
            % Update Originator, Citation, and Study Page details
            %
            search_result_items = app.SearchResults.Items(2:end);
            originator_selection = app.SearchResults.Value;
            if strcmp(originator_selection,'None')
              return
            else
              idx = find(strcmp(search_result_items, originator_selection));
              originator = app.api_full_json.study(idx).investigators;
              app.OriginatorTextArea.Value=originator;
              if isempty(app.api_full_json.study(idx).publication)
                app.CitationTextArea.Value='No citation found';
              else
                citation = app.api_full_json.study(idx).publication.citation;
                app.CitationTextArea.Value=citation;
              end
              studypage_base = 'https://www.ncei.noaa.gov/access/paleo-search/study/';
              studyId = app.api_full_json.study(idx).NOAAStudyId;
              app.StudyPageTextArea.Value = [studypage_base studyId];
            end
            
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            user_selection = questdlg('Are you sure you want to clear query parameters?',...
                'Clear','Yes','Cancel','Cancel');
            switch user_selection
                case 'Yes'
                    app.APIURL.Value = app.api_base;
                    latMin = -90;
                    latMax = 90;
                    lonMin = -180;
                    lonMax = 180;
                    app.LatMin.Value = latMin;
                    app.LatMax.Value = latMax;
                    app.LonMin.Value = lonMin;
                    app.LonMax.Value = lonMax;
                    %{
                      I have not decided whether I like the MapLimits
                      being edited or not. Un-comment the following line:
                    %}
                    % geolimits(app.map_axes,[latMin latMax],[lonMin lonMax]) 
                case 'Cancel'
                    % Do nothing
            end

        end

        % Value changed function: SearchResults
        function SearchResultsValueChanged(app, event)
            user_selection = app.SearchResults.Value;
            if strcmp(user_selection,'None')
                return
            end
            % idx = find(strcmp(app.SearchResults.Items, user_selection));
            idx = find(contains(app.SearchResults.Items, user_selection))-1;
            originator = app.api_full_json.study(idx).investigators;
            app.OriginatorTextArea.Value=originator;
            if isempty(app.api_full_json.study(idx).publication)
                app.CitationTextArea.Value='No citation found';
            else
                citation = app.api_full_json.study(idx).publication.citation;
                app.CitationTextArea.Value=citation;
            end
            studypage_base = 'https://www.ncei.noaa.gov/access/paleo-search/study/';
            studyId = app.api_full_json.study(idx).NOAAStudyId;
            app.StudyPageTextArea.Value = [studypage_base studyId];
            %
            % Add new map axes
            %
            sc = findobj(app.map_axes2,'Type','Scatter');
            if ~isempty(sc)
             [sc.Visible]=deal('off');
            end
            C = [244 50 12]./255; % Red
            number_of_sites = length(app.api_full_json.study(idx).site);
            if number_of_sites == 1
             lat=str2double(app.api_full_json.study(idx).site(1).geo.geometry.coordinates(1));
             lon=str2double(app.api_full_json.study(idx).site(1).geo.geometry.coordinates(2));
            elseif number_of_sites > 1
             lat=[];
             lon=[];
             for latitude = 1:number_of_sites
             for longitude= 1:number_of_sites
                lat_value = str2double(app.api_full_json.study(idx).site(latitude).geo.geometry.coordinates(1));
                lon_value = str2double(app.api_full_json.study(idx).site(longitude).geo.geometry.coordinates(2));
                lat = [lat;lat_value];
                lon = [lon;lon_value];
             end
             end
            end
             gs=geoscatter(app.map_axes2,lat,lon);
             gs.MarkerFaceColor=C;
             gs.MarkerEdgeColor='k';
             gs.SizeData=30;
           
        end

        % Button pushed function: GotostudypageButton
        function GotostudypageButtonPushed(app, event)
            % Get the URL for the study page from the Study Page Text Area
            studypage = app.StudyPageTextArea.Value;
            % Open the URL in the users browser
            web(char(studypage), '-browser');
        end

        % Button pushed function: DataDownloadButton
        function DataDownloadButtonPushed(app, event)

            % Get list of datasets in the Search Results panel
            datasets = app.SearchResults.Items;
            if contains(datasets,'None')
                return
            else
                % Get the selected dataset from the Search Results panel
                selected_dataset = app.SearchResults.Value;
                % Find the index position of the selected dataset
                index_of_selected_dataset = find(contains(datasets,selected_dataset))-1;
    
                % Store the full API variable as "JSON"
                JSON = app.api_full_json;
                % Index into "JSON" to get the selected dataset only
                JSON.study = JSON.study(index_of_selected_dataset);
                
                % Download all of the data files for the selected dataset
                download_study_data(JSON);
            end
            
        end

        % Value changed function: AdvancedDataTypeList
        function AdvancedDataTypeListValueChanged(app, event)
            % Get the selected data types
            selected_DataType = string(app.AdvancedDataTypeList.Value);
            dataTypes = get_dataTypeId();
            
            % Get a list of the appropriate investigators
            sz_DataTypes = size(selected_DataType,2);
            if sz_DataTypes > 1
                investigators = [];
                for sz = 1:sz_DataTypes
                    dataType = selected_DataType(sz);
                    dataTypeId = dataTypes.(dataType);
                    investigatorList = get_Investigators(dataTypeId);
                    investigators = [investigators; investigatorList];
                end
                investigators = sort(investigators);
            else
                dataTypeId = dataTypes.(selected_DataType);
                investigators = get_Investigators(dataTypeId);
            end
            
            % Add the investigators to the investigator item list
            app.InvestigatorList.Items = investigators;
            app.InvestigatorList.Multiselect = 'on';

        end

        % Button pushed function: AdvancedSearchButton
        function AdvancedSearchButtonPushed(app, event)
            %
            % Update API URL
            %
            dataTypeIds = get_dataTypeId();
            fields = fieldnames(dataTypeIds);
            user_selections = app.AdvancedDataTypeList.Value;
            idx = contains(fields,user_selections);
            
            % Data Types
            if isscalar(user_selections)
                id = dataTypeIds.(fields{idx});
                update_api = [char(string(app.api_base)) '&dataTypeId=' num2str(id)];
                app.AdvancedAPIURL.Value = update_api;
            else
                ids = cell2mat(struct2cell(dataTypeIds));
                ids = ids(idx);
                link = '%7C';
                update_api = [char(string(app.api_base)) '&dataTypeId=' num2str(ids(1))];
                for id = 2:length(ids)
                  update_api = [update_api link num2str(ids(id))];
                end
                app.AdvancedAPIURL.Value = update_api;
            end

            % Investigators
            all_investigators = app.InvestigatorList.Items;
            selected_investigators = app.InvestigatorList.Value;
            idx = contains(all_investigators,selected_investigators);
            if isscalar(selected_investigators)
                inv = all_investigators{idx};
                update_api = [update_api '&investigators=' char(inv)];
                %update_api = [update_api '&investigatorsAndOr=and'];
                app.AdvancedAPIURL.Value = update_api;
            else
                invs = all_investigators(idx);
                link = '|';
                update_api = [update_api '&investigators=' char(invs{1})];
                for investigator = 2:length(invs)
                    update_api = [update_api link char(invs{investigator})];
                end
                %update_api = [update_api '&investigatorsAndOr=and'];
                app.AdvancedAPIURL.Value = update_api;
            end

            % Latitude/Longitude
            latMin = app.AdvancedLatMin.Value;
            latMax = app.AdvancedLatMax.Value;
            lonMin = app.AdvancedLonMin.Value;
            lonMax = app.AdvancedLonMax.Value;
            if latMin ~= -90
             app.AdvancedAPIURL.Value = [char(app.AdvancedAPIURL.Value) '&minLat=' num2str(latMin)];
            end
            if latMax ~= 90
             app.AdvancedAPIURL.Value = [char(app.AdvancedAPIURL.Value) '&maxLat=' num2str(latMax)];
            end
            if lonMin ~= -180
             app.AdvancedAPIURL.Value = [char(app.AdvancedAPIURL.Value) '&minLon=' num2str(lonMin)];
            end
            if lonMax ~= 180
             app.AdvancedAPIURL.Value = [char(app.AdvancedAPIURL.Value) '&maxLon=' num2str(lonMax)];
            end

            % Elevation
            elevMin = app.ElevMin.Value;
            elevMax = app.ElevMax.Value;
            if ~isempty(elevMin)
             app.AdvancedAPIURL.Value = [char(app.AdvancedAPIURL.Value) '&minElev=' num2str(elevMin)];
            end
            if ~isempty(elevMax)
             app.AdvancedAPIURL.Value = [char(app.AdvancedAPIURL.Value) '&maxElev=' num2str(elevMax)];
            end

            % Time period
            isCE = app.CEButton.Value;
            if isCE
                timeFormat = 'CE';
            else
                timeFormat = 'BP';
            end
            earliestYear = app.TimeMin.Value;
            latestYear = app.TimeMax.Value;
            if ~isempty(earliestYear)
                app.AdvancedAPIURL.Value = [char(app.AdvancedAPIURL.Value) '&earliestYear=' num2str(earliestYear) '&timeFormat=' char(timeFormat)];
            end
            if ~isempty(latestYear)
                app.AdvancedAPIURL.Value = [char(app.AdvancedAPIURL.Value) '&latestYear=' num2str(latestYear) '&timeFormat=' char(timeFormat)];
            end


            % Finalize API URL
            app.AdvancedAPIURL.Value = [char(app.AdvancedAPIURL.Value) '&headersOnly=true'];

            %
            % Search results
            %
            api_url = char(app.AdvancedAPIURL.Value);
            options = weboptions('Timeout', 60);
            api_json = webread(api_url,options);
            if isempty(api_json)
                errordlg('No datasets with these parameters were found :(','Search Parameter Error')
                return
            end
            study_names = {api_json.study.studyName};
            none = {'None'};
            study_names = [none; study_names'];
            app.SearchResults.Items=study_names;

            % Get full header data
            api_full_url = api_url;
            api_full_url(end-16:end) = []; % Deletes &headersOnly=true
            app.api_full_json = webread(api_full_url,options);

        end

        % Button pushed function: AdvancedClearButton
        function AdvancedClearButtonPushed(app, event)
            user_selection = questdlg('Are you sure you want to clear query parameters?',...
                'Clear','Yes','Cancel','Cancel');
            switch user_selection
                case 'Yes'
                    app.AdvancedAPIURL.Value = app.api_base;
                    advancedLatMin = -90;
                    advancedLatMax = 90;
                    advancedLonMin = -180;
                    advancedLonMax = 180;
                    app.AdvancedLatMin.Value = advancedLatMin;
                    app.AdvancedLatMax.Value = advancedLatMax;
                    app.AdvancedLonMin.Value = advancedLonMin;
                    app.AdvancedLonMax.Value = advancedLonMax;
                    
                    app.InvestigatorList.Items = {'N/A'};

                    app.ElevMax.Value = [];
                    app.ElevMin.Value = [];

                    app.calyrBP01950CEButton.Value = false;
                    app.CEButton.Value=true;

                    app.TimeMin.Value = [];
                    app.TimeMax.Value = [];

                    %{
                      I have not decided whether I like the MapLimits
                      being edited or not. Un-comment the following line:
                    %}
                    % geolimits(app.map_axes,[latMin latMax],[lonMin lonMax]) 
                case 'Cancel'
                    % Do nothing
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.H1BackgroundLabel = uilabel(app.UIFigure);
            app.H1BackgroundLabel.Text = 'H1 Background';
            app.H2BackgroundLabel = uilabel(app.UIFigure);
            app.H2BackgroundLabel.Text = 'H2 Background';
            app.ForegroundLabel = uilabel(app.UIFigure);
            app.ForegroundLabel.Text = 'Foreground';
            app.ButtonLabel = uilabel(app.UIFigure);
            app.ButtonLabel.Text = 'Button';
            app.H3BackgroundLabel = uilabel(app.UIFigure);
            app.H3BackgroundLabel.Text = 'H3 Background';
            app.HighlightLabel = uilabel(app.UIFigure);
            app.HighlightLabel.Text = 'Highlight';
            app.Label_8 = uilabel(app.UIFigure);
            app.Label_8.Text = '[ 17  46  81]./255';
            app.Label_9 = uilabel(app.UIFigure);
            app.Label_9.Text = '[ 76  76  76]./255';
            app.Label_10 = uilabel(app.UIFigure);
            app.Label_10.Text = '[255 255 255]./255';
            app.Label_11 = uilabel(app.UIFigure);
            app.Label_11.Text = '[ 36  91 171]./255';
            app.Label_12 = uilabel(app.UIFigure);
            app.Label_12.Text = '[229 241 251]./255';
            app.Label_14 = uilabel(app.UIFigure);
            app.Label_14.Text = '[214 144 114]./255';
            app.COLORSLabel = uilabel(app.UIFigure);
            app.COLORSLabel.Text = 'COLORS';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.GeneralSearchTab = uitab(app.TabGroup);
            app.GeneralSearchTab.Title = 'General Search';
            app.Label_APIURL = uilabel(app.GeneralSearchTab);
            app.Label_APIURL.Text = {'API URL for'; 'these parameters'};
            app.Label_DataType = uilabel(app.GeneralSearchTab);
            app.Label_DataType.Text = 'Data Type';
            app.Label_DataTypeTip = uilabel(app.GeneralSearchTab);
            app.Label_DataTypeTip.Text = 'Select 1 data type';
            app.DataTypeList = uilistbox(app.GeneralSearchTab);
            app.APIURL = uitextarea(app.GeneralSearchTab);
            app.APIURL.Value = {'https://www.ncei.noaa.gov/access/paleo-search/study/search.json?dataPublisher=NOAA'};
            app.SearchButton = uibutton(app.GeneralSearchTab, 'push');
            app.SearchButton.ButtonPushedFcn = createCallbackFcn(app, @SearchButtonPushed, true);
            app.SearchButton.Text = 'Search';
            app.Label_MinLat = uilabel(app.GeneralSearchTab);
            app.Label_MinLat.Text = 'Min';
            app.Label_MaxLat = uilabel(app.GeneralSearchTab);
            app.Label_MaxLat.Text = 'Max';
            app.LatMin = uieditfield(app.GeneralSearchTab, 'numeric');
            app.LatMin.Limits = [-90 89];
            app.LatMin.Value = -90;
            app.Label_MinLon = uilabel(app.GeneralSearchTab);
            app.Label_MinLon.Text = 'Min';
            app.Label_MaxLon = uilabel(app.GeneralSearchTab);
            app.Label_MaxLon.Text = 'Max';
            app.LonMin = uieditfield(app.GeneralSearchTab, 'numeric');
            app.LonMin.Limits = [-180 179];
            app.LonMin.Value = -180;
            app.Label_Longitude = uilabel(app.GeneralSearchTab);
            app.Label_Longitude.Text = 'Longitude (°E)';
            app.Label_Latitude = uilabel(app.GeneralSearchTab);
            app.Label_Latitude.Text = 'Latitude (°N)';
            app.MapAxes = uipanel(app.GeneralSearchTab);
            app.MapAxes.Title = 'Study Distribution';
            app.LatMax = uieditfield(app.GeneralSearchTab, 'numeric');
            app.LatMax.Limits = [-89 90];
            app.LatMax.Value = 90;
            app.LonMax = uieditfield(app.GeneralSearchTab, 'numeric');
            app.LonMax.Limits = [-179 180];
            app.LonMax.Value = 180;
            app.ClearButton = uibutton(app.GeneralSearchTab, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'C';
            app.AdvancedSearchTab_2 = uitab(app.TabGroup);
            app.AdvancedSearchTab_2.Title = 'Advanced Search';
            app.ButtonGroup = uibuttongroup(app.AdvancedSearchTab_2);
            app.CEButton = uiradiobutton(app.ButtonGroup);
            app.CEButton.Text = 'CE';
            app.CEButton.Value = true;
            app.calyrBP01950CEButton = uiradiobutton(app.ButtonGroup);
            app.calyrBP01950CEButton.Text = 'cal. yr BP (0 = 1950 CE)';
            app.Label_APIURL_2 = uilabel(app.AdvancedSearchTab_2);
            app.Label_APIURL_2.Text = {'API URL for'; 'these parameters'};
            app.Label_DataType_11 = uilabel(app.AdvancedSearchTab_2);
            app.Label_DataType_11.Text = 'Data Type';
            app.Label_DataTypeTip_2 = uilabel(app.AdvancedSearchTab_2);
            app.Label_DataTypeTip_2.Text = 'Hold CTRL to select multiple data types or multiple investigators.';
            app.AdvancedDataTypeList = uilistbox(app.AdvancedSearchTab_2);
            app.AdvancedDataTypeList.ValueChangedFcn = createCallbackFcn(app, @AdvancedDataTypeListValueChanged, true);
            app.AdvancedAPIURL = uitextarea(app.AdvancedSearchTab_2);
            app.AdvancedAPIURL.Value = {'https://www.ncei.noaa.gov/access/paleo-search/study/search.json?dataPublisher=NOAA'};
            app.Label_MinLat_2 = uilabel(app.AdvancedSearchTab_2);
            app.Label_MinLat_2.Text = 'Min';
            app.Label_MaxLat_2 = uilabel(app.AdvancedSearchTab_2);
            app.Label_MaxLat_2.Text = 'Max';
            app.AdvancedLatMin = uieditfield(app.AdvancedSearchTab_2, 'numeric');
            app.AdvancedLatMin.Limits = [-90 89];
            app.AdvancedLatMin.Value = -90;
            app.Label_MinLon_2 = uilabel(app.AdvancedSearchTab_2);
            app.Label_MinLon_2.Text = 'Min';
            app.Label_MaxLon_2 = uilabel(app.AdvancedSearchTab_2);
            app.Label_MaxLon_2.Text = 'Max';
            app.AdvancedLonMin = uieditfield(app.AdvancedSearchTab_2, 'numeric');
            app.AdvancedLonMin.Limits = [-180 179];
            app.AdvancedLonMin.Value = -180;
            app.Label_Longitude_2 = uilabel(app.AdvancedSearchTab_2);
            app.Label_Longitude_2.Text = 'Longitude (°E)';
            app.Label_Latitude_2 = uilabel(app.AdvancedSearchTab_2);
            app.Label_Latitude_2.Text = 'Latitude (°N)';
            app.AdvancedLatMax = uieditfield(app.AdvancedSearchTab_2, 'numeric');
            app.AdvancedLatMax.Limits = [-89 90];
            app.AdvancedLatMax.Value = 90;
            app.AdvancedLonMax = uieditfield(app.AdvancedSearchTab_2, 'numeric');
            app.AdvancedLonMax.Limits = [-179 180];
            app.AdvancedLonMax.Value = 180;
            app.Label_ElevMin = uilabel(app.AdvancedSearchTab_2);
            app.Label_ElevMin.Text = 'Min';
            app.Label_ElevMax = uilabel(app.AdvancedSearchTab_2);
            app.Label_ElevMax.Text = 'Max';
            app.ElevMin = uieditfield(app.AdvancedSearchTab_2, 'numeric');
            app.ElevMin.AllowEmpty = 'on';
            app.ElevMin.Value = [];
            app.Label_Elevation = uilabel(app.AdvancedSearchTab_2);
            app.Label_Elevation.Text = 'Elevation (m)';
            app.ElevMax = uieditfield(app.AdvancedSearchTab_2, 'numeric');
            app.ElevMax.AllowEmpty = 'on';
            app.ElevMax.Value = [];
            app.Label_Investigator = uilabel(app.AdvancedSearchTab_2);
            app.Label_Investigator.Text = 'Investigator';
            app.InvestigatorList = uilistbox(app.AdvancedSearchTab_2);
            app.AdvancedSearchButton = uibutton(app.AdvancedSearchTab_2, 'push');
            app.AdvancedSearchButton.ButtonPushedFcn = createCallbackFcn(app, @AdvancedSearchButtonPushed, true);
            app.AdvancedSearchButton.Text = 'Search';
            app.AdvancedClearButton = uibutton(app.AdvancedSearchTab_2, 'push');
            app.AdvancedClearButton.ButtonPushedFcn = createCallbackFcn(app, @AdvancedClearButtonPushed, true);
            app.AdvancedClearButton.Text = 'Clear';
            app.Label_Time = uilabel(app.AdvancedSearchTab_2);
            app.Label_Time.Text = 'Time';
            app.Label_EarliestTime = uilabel(app.AdvancedSearchTab_2);
            app.Label_EarliestTime.Text = 'Earliest';
            app.Label_LatestTime = uilabel(app.AdvancedSearchTab_2);
            app.Label_LatestTime.Text = 'Latest';
            app.TimeMin = uieditfield(app.AdvancedSearchTab_2, 'numeric');
            app.TimeMin.AllowEmpty = 'on';
            app.TimeMin.Value = [];
            app.TimeMax = uieditfield(app.AdvancedSearchTab_2, 'numeric');
            app.TimeMax.AllowEmpty = 'on';
            app.TimeMax.Value = [];
            app.Label_DataType_10 = uilabel(app.UIFigure);
            app.Label_DataType_10.Text = '  Search Results';
            app.SearchResults = uilistbox(app.UIFigure);
            app.SearchResults.Items = {'None'};
            app.SearchResults.ValueChangedFcn = createCallbackFcn(app, @SearchResultsValueChanged, true);
            app.SearchResults.Value = 'None';
            app.Label_Originator = uilabel(app.UIFigure);
            app.Label_Originator.Text = 'Originator';
            app.OriginatorTextArea = uitextarea(app.UIFigure);
            app.OriginatorTextArea.Value = {'Originator'};
            app.Label_Citation = uilabel(app.UIFigure);
            app.Label_Citation.Text = 'Citation Information';
            app.CitationTextArea = uitextarea(app.UIFigure);
            app.CitationTextArea.Value = {'Citation'};
            app.Label_StudyPage = uilabel(app.UIFigure);
            app.Label_StudyPage.Text = 'NOAA Study Page';
            app.StudyPageTextArea = uitextarea(app.UIFigure);
            app.StudyPageTextArea.Value = {'Study page'};
            app.GotostudypageButton = uibutton(app.UIFigure, 'push');
            app.GotostudypageButton.ButtonPushedFcn = createCallbackFcn(app, @GotostudypageButtonPushed, true);
            app.GotostudypageButton.Text = 'Go to study page';
            app.StudyLocation = uipanel(app.UIFigure);
            app.DataDownloadButton = uibutton(app.UIFigure, 'push');
            app.DataDownloadButton.ButtonPushedFcn = createCallbackFcn(app, @DataDownloadButtonPushed, true);
            app.DataDownloadButton.Text = 'Data Download';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = paleoDataSearch

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end